classdef selectRuns < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        dataTable                 matlab.ui.control.Table
        SwitchAllHighlightedEntriesButton  matlab.ui.control.Button
        DataSelectionToolLabel    matlab.ui.control.Label
        ThistoolwillhelpyouLabel  matlab.ui.control.Label
        Step1Label                matlab.ui.control.Label
        Step1Label_2              matlab.ui.control.Label
        generateButton            matlab.ui.control.Button
        Step1Label_3              matlab.ui.control.Label
        VariablevariedacrosseachselectedrunLabel  matlab.ui.control.Label
        CicerovariablesheldconstantwithineachrunLabel  matlab.ui.control.Label
        CicerovariablesheldconstantacrossallselectedrunsLabel  matlab.ui.control.Label
        TheseinputsareoptionalUsedfortitlesaxeslegendsLabel  matlab.ui.control.Label
        Step1Label_4              matlab.ui.control.Label
        varVariableTable          matlab.ui.control.Table
        Step6SaveplotvariablestoworkspaceButton  matlab.ui.control.Button
        MessageBoxTextAreaLabel   matlab.ui.control.Label
        MsgBox                    matlab.ui.control.TextArea
        ManualVariedVarCiceroVarEditFieldLabel  matlab.ui.control.Label
        ManualVariedVarCiceroVarEditField  matlab.ui.control.EditField
        ManualVariedVarCheck      matlab.ui.control.CheckBox
        heldvarEachTable          matlab.ui.control.Table
        heldvarAllTable           matlab.ui.control.Table
        RemembertorununpackRunVarsRunVarsLabel  matlab.ui.control.Label
        ManualHeldVarsEachLabel   matlab.ui.control.Label
        ManualHeldVarsEach        matlab.ui.control.EditField
        ManualHeldVarsAllEditFieldLabel  matlab.ui.control.Label
        ManualHeldVarsAll         matlab.ui.control.EditField
    end

    
    properties (Access = private)
        table_selected % Selected cells in the dataTable (used to make switching multiple possible)
        InputData % The input RunDataLibrary
        thisRunDataLibrary % the output RunDataLibrary
        var_specs
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function [variedVarList,sizes] = getVariedVarList(app)
            varsList = app.thisRunDataLibrary.RunProperties.vars;
            fns = fieldnames(varsList);
            ind = 1;
            for ii = 1:numel(fns)
                if numel(varsList.(fns{ii})) > 1
%                     sizes(ind) = strjoin(string( size(varsList.(fns{ii})) ),' by ');
                    sizes(ind) = string(size(varsList.(fns{ii}),1));
                    variedVarList(ind) = string(fns{ii});
                    ind = ind + 1;
                end
            end
            variedVarList = variedVarList';
            sizes = sizes';
        end
        
        function [varNameList,sizes] = getVarList(app)
            varsList = app.thisRunDataLibrary.RunProperties.vars;
            fns = fieldnames(varsList);
            ind = 1;
            for ii = 1:numel(fns)
                if ~isempty(varsList.(fns{ii}))
%                     sizes(ind) = strjoin(string( size(varsList.(fns{ii})) ),' by ');
                    sizes(ind) = string(size(varsList.(fns{ii}),1));
                    varNameList(ind) = string(fns{ii});
                    ind = ind + 1;
                end
            end
            varNameList = varNameList';
            sizes = sizes';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, InputData)
            if(nargin<2)
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%');
                disp('%%%%%%%%You must have the RunDataLibrary object as an input!!%%%%%%%%');
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%');
            end
            
            %collect the information from the RunDataLibrary (RunIDs,
            %Folders, dates etc..)
            %include boolean is choice of the user
            RunIDs = cellfun(@(x) x.RunID, InputData.RunDatas,'UniformOutput',false);
            Folders = cellfun(@(x) x.RunFolder, InputData.RunDatas,'UniformOutput',false);
            dates = cellfun(@(x) datetime(x.Year,x.Month,x.Day),InputData.RunDatas,'UniformOutput',false);
            include_bool = false(size(Folders));
            
            app.dataTable.Data = table(Folders,dates,RunIDs,include_bool);
            app.InputData = InputData;
        end

        % Button pushed function: SwitchAllHighlightedEntriesButton
        function SwitchAllHighlightedEntriesButtonPushed(app, event)
            curr_bool = app.dataTable.Data{app.table_selected(1,1),4};
            new_bool = not(curr_bool);
            
            for ii = 1:size(app.table_selected,1)
                app.dataTable.Data{app.table_selected(ii,1),4} = new_bool;
            end
        end

        % Cell selection callback: dataTable
        function dataTableCellSelection(app, event)
            indices = event.Indices;
            
            app.table_selected = indices;
        end

        % Button pushed function: generateButton
        function generateButtonPushed(app, event)
            tab = app.dataTable.Data;
            incl_RunIDs = tab.RunIDs(tab.include_bool);
            
            condition = {'RunID',incl_RunIDs};
            
            app.var_specs.condition = condition;
            
            ThisData = RunDataLibrary();
            ThisData = ThisData.libraryConstruct(app.InputData,condition);
            ThisRunData = ThisData.RunDatas;
            
            app.thisRunDataLibrary = ThisData;
            
            %%NOTE that in general assignin is something that we should try
            %%to avoid. However, in this case we want to make it REALLY
            %%easy for the end user, and just have the new rundata show up
            %%in their matlab workspace. A better approach is to use
            %%function outputs, but the workaround for appdesigner is a bit
            %%cumbersome...
            
%             assignin('base',app.nameEditField.Value,ThisRunData);
%             assignin('base',strcat(app.nameEditField.Value,'Library'),ThisData);
%             assignin("base",strcat(app.nameEditField.Value,'Variable_Specs'),var_specs);
            
            assignin('base','RunDatas',ThisRunData);
            assignin('base','RunDataLib',ThisData);
            msgstring = "RunDatas and RunDataLibrary saved to workspace. Don't worry about all the error messages! They just mean that a variable was added to later runs that was not present in the runs you selected.";
            disp(msgstring);
            app.MsgBox.Value = msgstring;
            
            % Populate the varied variables table from the RunDataLibrary
            % that was just generated
            [variedVars,sizes]  = getVariedVarList(app);
            varChecks = false(size(variedVars));
            app.varVariableTable.Data = table(variedVars,sizes,varChecks);
            
            [varNameList,sizes] = getVarList(app);
            varChecks2 = false(size(varNameList));
            app.heldvarEachTable.Data = table(varNameList,sizes,varChecks2);
            app.heldvarAllTable.Data = table(varNameList,sizes,varChecks2);
            
        end

        % Button pushed function: 
        % Step6SaveplotvariablestoworkspaceButton
        function saveVarsToWorkspace(app, event)
            
            % grab the chosen varied variable value
            if ~app.ManualVariedVarCheck.Value
                varVarTable = app.varVariableTable.Data;
                varied_var = varVarTable.variedVars(varVarTable.varChecks);
                
                if numel(varied_var) > 1
                    app.MsgBox.Value = "Too many variables selected. There can be only one!";
                    return;
                end
            else
                varied_var = app.ManualVariedVarCiceroVarEditField.Value;
            end
            varied_var = cellstr(varied_var);
            
            % get the chosen heldvars
            heldvarEachTab = app.heldvarEachTable.Data;
            heldvarAllTab = app.heldvarAllTable.Data;
            
            heldvars_each = heldvarEachTab.varNameList(heldvarEachTab.varChecks2);
            heldvars_all = heldvarAllTab.varNameList(heldvarAllTab.varChecks2);
            
            % add in the manual heldvars each
            if ~isempty(app.ManualHeldVarsEach.Value)
                new_heldvars_each = split(strrep(app.ManualHeldVarsEach.Value,' ',''),',');
                for ii = 1:length(new_heldvars_each)
                    heldvars_each{end+1} = new_heldvars_each{ii};
                end
            end
            
            heldvars_each = cellstr(heldvars_each);
            if isempty(heldvars_each)
                heldvars_each = {};
            end
            
            % add in the manual heldvars all
            if ~isempty(app.ManualHeldVarsAll.Value)
                new_heldvars_all = split(strrep(app.ManualHeldVarsAll.Value,' ',''),',');
                for ii = 1:length(new_heldvars_all)
                    heldvars_all{end+1} = new_heldvars_all{ii};
                end
            end
            
            heldvars_all = cellstr(heldvars_all);
            if isempty(heldvars_all)
                heldvars_all = {};
            end
            
            app.var_specs.varied_var = varied_var;
            app.var_specs.heldvars_each = heldvars_each;
            app.var_specs.heldvars_all = heldvars_all;
            
            vars = app.var_specs;
            assignin("base",'RunVars',vars);
            
            disp('RunVars saved to workspace.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.dataTable = uitable(app.UIFigure);
            app.dataTable.ColumnName = {'Folder'; 'Date'; 'RunID (DataManager)'; 'Include?'};
            app.dataTable.RowName = {};
            app.dataTable.ColumnSortable = true;
            app.dataTable.ColumnEditable = [false false false true];
            app.dataTable.CellSelectionCallback = createCallbackFcn(app, @dataTableCellSelection, true);
            app.SwitchAllHighlightedEntriesButton = uibutton(app.UIFigure, 'push');
            app.SwitchAllHighlightedEntriesButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchAllHighlightedEntriesButtonPushed, true);
            app.SwitchAllHighlightedEntriesButton.Text = {'Switch'; 'All  '; 'Highlighted'; 'Entries'};
            app.DataSelectionToolLabel = uilabel(app.UIFigure);
            app.DataSelectionToolLabel.Text = 'Data Selection Tool';
            app.ThistoolwillhelpyouLabel = uilabel(app.UIFigure);
            app.ThistoolwillhelpyouLabel.Text = {'This tool will help you automatically select a subset of runs from the RunDataLibrary that you generated using AutoLibraryGenerator.'; 'The idea is you would want to select a smaller subset of the above to plot/compare easily.'; '(once again make sure to have dataManager and subfolders added to path.)'};
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step 1: This tool should be run from the MATLAB, with the RunDataLibrary as an argument (i.e. >>runConditions(Data)) ';
            app.Step1Label_2 = uilabel(app.UIFigure);
            app.Step1Label_2.Text = 'Step 2: Choose the runs that you want to plot/analyze from the list below: ';
            app.generateButton = uibutton(app.UIFigure, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generateButton.Text = 'Step 3:  Generate data subset and save to workspace';
            app.Step1Label_3 = uilabel(app.UIFigure);
            app.Step1Label_3.Text = 'Step 4: Choose changing variable (select ONE).';
            app.VariablevariedacrosseachselectedrunLabel = uilabel(app.UIFigure);
            app.VariablevariedacrosseachselectedrunLabel.Text = {'Variable varied within each selected run. '; 'If not present above, type below and hit the checkbox.'};
            app.CicerovariablesheldconstantwithineachrunLabel = uilabel(app.UIFigure);
            app.CicerovariablesheldconstantwithineachrunLabel.Text = {'Cicero variables held constant'; 'within each run (can change across runs)'};
            app.CicerovariablesheldconstantacrossallselectedrunsLabel = uilabel(app.UIFigure);
            app.CicerovariablesheldconstantacrossallselectedrunsLabel.Text = {'Cicero variables held constant'; 'across all selected runs.'};
            app.TheseinputsareoptionalUsedfortitlesaxeslegendsLabel = uilabel(app.UIFigure);
            app.TheseinputsareoptionalUsedfortitlesaxeslegendsLabel.Text = 'These inputs are optional. Used for titles/axes/legends.';
            app.Step1Label_4 = uilabel(app.UIFigure);
            app.Step1Label_4.Text = 'Step 5: Input cicero variables that are held constant across each/all runs.';
            app.varVariableTable = uitable(app.UIFigure);
            app.varVariableTable.ColumnName = {'Variable Name'; 'Size'; 'Select'};
            app.varVariableTable.RowName = {};
            app.varVariableTable.ColumnSortable = true;
            app.varVariableTable.ColumnEditable = [false false true];
            app.Step6SaveplotvariablestoworkspaceButton = uibutton(app.UIFigure, 'push');
            app.Step6SaveplotvariablestoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @saveVarsToWorkspace, true);
            app.Step6SaveplotvariablestoworkspaceButton.Text = 'Step 6: Save plot variables to workspace.';
            app.MessageBoxTextAreaLabel = uilabel(app.UIFigure);
            app.MessageBoxTextAreaLabel.Text = 'Message Box';
            app.MsgBox = uitextarea(app.UIFigure);
            app.ManualVariedVarCiceroVarEditFieldLabel = uilabel(app.UIFigure);
            app.ManualVariedVarCiceroVarEditFieldLabel.Text = {'Manual'; 'Varied Var'; '(Cicero Var)'};
            app.ManualVariedVarCiceroVarEditField = uieditfield(app.UIFigure, 'text');
            app.ManualVariedVarCheck = uicheckbox(app.UIFigure);
            app.ManualVariedVarCheck.Text = '';
            app.heldvarEachTable = uitable(app.UIFigure);
            app.heldvarEachTable.ColumnName = {'Variable Names'; 'Size'; 'Select'};
            app.heldvarEachTable.RowName = {};
            app.heldvarEachTable.ColumnSortable = true;
            app.heldvarEachTable.ColumnEditable = [false false true];
            app.heldvarAllTable = uitable(app.UIFigure);
            app.heldvarAllTable.ColumnName = {'Variable Names'; 'Size'; 'Select'};
            app.heldvarAllTable.RowName = {};
            app.heldvarAllTable.ColumnSortable = true;
            app.heldvarAllTable.ColumnEditable = [false false true];
            app.RemembertorununpackRunVarsRunVarsLabel = uilabel(app.UIFigure);
            app.RemembertorununpackRunVarsRunVarsLabel.Text = {'This will save the struct containing varied_variable and held_vars to the workspace.'; 'After this, run unpackRunVars(RunVars).'};
            app.ManualHeldVarsEachLabel = uilabel(app.UIFigure);
            app.ManualHeldVarsEachLabel.Text = {'Manual'; 'HeldVars'; 'Each'};
            app.ManualHeldVarsEach = uieditfield(app.UIFigure, 'text');
            app.ManualHeldVarsEach.Tooltip = {'CSV, Cicero Variables, spaces OK.'};
            app.ManualHeldVarsAllEditFieldLabel = uilabel(app.UIFigure);
            app.ManualHeldVarsAllEditFieldLabel.Text = {'Manual'; 'HeldVars'; 'All'};
            app.ManualHeldVarsAll = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectRuns(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end